classdef FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GraphicalOutputsPanel          matlab.ui.container.Panel
        UploadButton                   matlab.ui.control.Button
        HRVAxes                        matlab.ui.control.UIAxes
        HeartRateAnalysisAxes          matlab.ui.control.UIAxes
        StatisticalOutputsPanel        matlab.ui.container.Panel
        RiskEditField                  matlab.ui.control.EditField
        RiskEditFieldLabel             matlab.ui.control.Label
        yearsLabel_2                   matlab.ui.control.Label
        EstHeartAgeEditField           matlab.ui.control.NumericEditField
        EstHeartAgeEditFieldLabel      matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        bpmLabel                       matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        YearRiskEditField              matlab.ui.control.NumericEditField
        YearRiskEditFieldLabel         matlab.ui.control.Label
        WHREditField                   matlab.ui.control.NumericEditField
        WHREditFieldLabel              matlab.ui.control.Label
        BMIEditField                   matlab.ui.control.NumericEditField
        BMIEditFieldLabel              matlab.ui.control.Label
        RREditField                    matlab.ui.control.NumericEditField
        RREditFieldLabel               matlab.ui.control.Label
        MaxHREditField                 matlab.ui.control.NumericEditField
        MaxHREditFieldLabel            matlab.ui.control.Label
        InputParametersPanel           matlab.ui.container.Panel
        inLabel_2                      matlab.ui.control.Label
        zeroCheck                      matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        CalculateButton                matlab.ui.control.Button
        mgdLLabel_2                    matlab.ui.control.Label
        HDLEditField                   matlab.ui.control.NumericEditField
        HDLEditFieldLabel              matlab.ui.control.Label
        BiologicalSexDropDownLabel     matlab.ui.control.Label
        BiologicalSexDropDown          matlab.ui.control.DropDown
        mgdLLabel                      matlab.ui.control.Label
        CholestrolLevelEditField       matlab.ui.control.NumericEditField
        CholestrolLevelEditFieldLabel  matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        ActiveSmokerDropDown           matlab.ui.control.DropDown
        ActiveSmokerDropDownLabel      matlab.ui.control.Label
        mmHgLabel                      matlab.ui.control.Label
        inLabel                        matlab.ui.control.Label
        kgLabel                        matlab.ui.control.Label
        yearsLabel                     matlab.ui.control.Label
        SBPEditField                   matlab.ui.control.NumericEditField
        SBPEditFieldLabel              matlab.ui.control.Label
        HipCircEditField               matlab.ui.control.NumericEditField
        HipCircEditFieldLabel          matlab.ui.control.Label
        WaistCircEditField             matlab.ui.control.NumericEditField
        WaistCircEditFieldLabel        matlab.ui.control.Label
        WeightEditField                matlab.ui.control.NumericEditField
        WeightEditFieldLabel           matlab.ui.control.Label
        AgeEditField                   matlab.ui.control.NumericEditField
        AgeEditFieldLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Initializing Variables
            age = app.AgeEditField.Value;
            gender = app.BiologicalSexDropDown.Value;
            weight = app.WeightEditField.Value;
            height = app.HeightEditField.Value;
            waistCirc = app.WaistCircEditField.Value;
            hipCirc = app.HipCircEditField.Value;
            SBP = app.SBPEditField.Value;
            Chol = app.CholestrolLevelEditField.Value;
            HDL = app.HDLEditField.Value;
            activeSmoke = app.ActiveSmokerDropDown.Value;
       
            % Toggle Button
            anyZeros = (age==0) || (weight==0) || (height==0) ||...
                        (waistCirc==0) || (hipCirc==0) || (SBP==0) ||...
                        (Chol==0) || (HDL==0);

            if anyZeros == false
                app.zeroCheck.Text = " ";

                % Calculating Max Heart Rate
                maxHeartRate = 208-age*0.7;
                app.MaxHREditField.Value = maxHeartRate;

                % Calculating RR
                RR = 60/maxHeartRate;             
                app.RREditField.Value = RR;

                % Calculating BMI
                BMI = weight/(height^2);
                app.BMIEditField.Value = BMI;

                % Calculating Waist-to-Hip Ratio
                WHR = waistCirc/hipCirc;
                app.WHREditField.Value = WHR;
                
                % Calculating 10-year Risk;
                points = 0;
                if gender == "Male"
                    if age <= 39
                       % Points based on age
                       points = points - 7;
                       % Points based on cholestrol levels
                       if Chol < 160
                            points = points + 0;
                       elseif Chol >= 160 && Chol < 200
                            points = points + 4;
                       elseif Chol >= 200 && Chol < 240
                            points = points + 7;
                       elseif Chol >= 240 && Chol < 280
                            points = points + 9;
                       else
                            points = points + 11;
                       end
                       % Points based on smoking status
                       if activeSmoke == "Yes"
                            points = points + 8;
                       end
                    elseif age >= 40 && age < 50
                       % Points based on age
                       points = points + 2;
                       % Points based on cholestrol levels
                       if Chol < 160
                            points = points + 0;
                       elseif Chol >= 160 && Chol < 200
                            points = points + 3;
                       elseif Chol >= 200 && Chol < 240
                            points = points + 5;
                       elseif Chol >= 240 && Chol < 280
                            points = points + 6;
                       else
                            points = points + 8;
                       end
                       % Points based on smoking status
                       if activeSmoke == "Yes"
                            points = points + 5;
                       end
                       
                    elseif age >= 50 && age < 60
                       % Points based on age
                       points = points + 7;
                       % Points based on cholestrol levels
                       if Chol < 160
                            points = points + 0;
                       elseif Chol >= 160 && Chol < 200
                            points = points + 2;
                       elseif Chol >= 200 && Chol < 240
                            points = points + 3;
                       elseif Chol >= 240 && Chol < 280
                            points = points + 4;
                       else
                            points = points + 5;
                       end
                       % Points based on smoking status
                       if activeSmoke == "Yes"
                            points = points + 3;
                       end
                       
                    elseif age >= 60 && age < 70
                       % Points based on age
                       points = points + 10;
                       % Points based on cholestrol levels
                       if Chol < 160
                            points = points + 0;
                       elseif Chol >= 160 && Chol < 200
                            points = points + 1;
                       elseif Chol >= 200 && Chol < 240
                            points = points + 1;
                       elseif Chol >= 240 && Chol < 280
                            points = points + 2;
                       else
                            points = points + 3;
                       end
                       % Points based on smoking status
                       if activeSmoke == "Yes"
                            points = points + 1;
                       end
                       
                    else
                        % Points based on age
                       points = points + 13;
                       % Points based on cholestrol levels
                       if Chol < 160
                            points = points + 0;
                       elseif Chol >= 160 && Chol < 200
                            points = points + 0;
                       elseif Chol >= 200 && Chol < 240
                            points = points + 0;
                       elseif Chol >= 240 && Chol < 280
                            points = points + 1;
                       else
                            points = points + 1;
                       end
                       % Points based on smoking status
                       if activeSmoke == "Yes"
                            points = points + 1;
                       end
                       
                    end

                    % Points based on HDL
                       if HDL >= 60
                            points = points - 1;
                       elseif HDL < 60 && HDL >=50
                            points = points + 0;
                       elseif HDL < 50 && HDL >= 40
                            points = points + 1;
                       else
                            points = points + 2;
                       end
                       % Points based on SBP
                       if SBP <120
                            points = points + 0;
                       elseif SBP >= 120 && SBP < 130
                            points = points + 0;
                       elseif SBP >= 130 && SBP < 140
                            points = points + 1;
                       elseif SBP >= 140 && SBP < 150
                            points = points + 1;
                       else
                            points = points + 2;
                       end

                       % Calculating 10-year Risk 
                       if points < 1
                            app.YearRiskEditField.Value = 0;
                       elseif points >= 1 && points < 5
                            app.YearRiskEditField.Value = 1;
                       elseif points >= 5 && points < 7
                            app.YearRiskEditField.Value = 2;
                       elseif points == 7
                            app.YearRiskEditField.Value = 3;
                       elseif points == 8
                            app.YearRiskEditField.Value = 4;
                       elseif points == 9
                            app.YearRiskEditField.Value = 5;
                       elseif points == 10
                            app.YearRiskEditField.Value = 6;
                       elseif points == 11
                            app.YearRiskEditField.Value = 8;
                       elseif points == 12
                            app.YearRiskEditField.Value = 10;
                       elseif points == 13
                            app.YearRiskEditField.Value = 12;
                       elseif points == 14
                            app.YearRiskEditField.Value = 16;
                       elseif points == 15
                            app.YearRiskEditField.Value = 20;
                       elseif points == 16
                            app.YearRiskEditField.Value = 25;
                       else
                            app.YearRiskEditField.Value = 30;
                       end

                       % Calculating Estimated Heart rate
                       if points < 0
                            app.EstHeartAgeEditField.Value = 30;
                       elseif points == 0
                            app.EstHeartAgeEditField.Value = 30;
                       elseif points == 1
                            app.EstHeartAgeEditField.Value = 32;
                       elseif points == 2
                            app.EstHeartAgeEditField.Value = 34;
                       elseif points == 3
                            app.EstHeartAgeEditField.Value = 36;
                       elseif points == 4
                            app.EstHeartAgeEditField.Value = 38;
                       elseif points == 5
                            app.EstHeartAgeEditField.Value = 40;
                       elseif points == 6
                            app.EstHeartAgeEditField.Value = 42;
                       elseif points == 7
                            app.EstHeartAgeEditField.Value = 45;
                       elseif points == 8
                            app.EstHeartAgeEditField.Value = 48;
                       elseif points == 9
                            app.EstHeartAgeEditField.Value = 51;
                       elseif points == 10
                            app.EstHeartAgeEditField.Value = 54;
                       elseif points == 11
                            app.EstHeartAgeEditField.Value = 57;
                       elseif points == 12
                            app.EstHeartAgeEditField.Value = 60;
                       elseif points == 13
                            app.EstHeartAgeEditField.Value = 64;
                       elseif points == 14
                            app.EstHeartAgeEditField.Value = 68;
                       elseif points == 15
                            app.EstHeartAgeEditField.Value = 72;
                       elseif points == 16
                            app.EstHeartAgeEditField.Value = 76;
                       else
                            app.EstHeartAgeEditField.Value = 80;
                       end

              % Determining Risk Category
                    YearRisk = app.YearRiskEditField.Value;
                        if YearRisk < 10
                            app.RiskEditField.Value="Low Risk";
                        elseif YearRisk >=10 && YearRisk <= 19
                            app.RiskEditField.Value="Moderate Risk";
                        elseif YearRisk >=20 && YearRisk <=30
                            app.RiskEditField.Value= "High Risk";
                        else
                            app.RiskEditField.Value='Invalid Input' ;
                        end

                else              
                    if age <= 39
                       % Points based on age
                       points = points - 5;
                       % Points based on cholestrol levels
                       if Chol < 160
                            points = points + 0;
                       elseif Chol >= 160 && Chol < 200
                            points = points + 4;
                       elseif Chol >= 200 && Chol < 240
                            points = points + 8;
                       elseif Chol >= 240 && Chol < 280
                            points = points + 11;
                       else
                            points = points + 13;
                       end
                       % Points based on smoking status
                       if activeSmoke == "Yes"
                            points = points + 9;
                       end
                       
                    elseif age >= 40 && age < 50
                       % Points based on age
                       points = points + 2;
                       % Points based on cholestrol levels
                       if Chol < 160
                            points = points + 0;
                       elseif Chol >= 160 && Chol < 200
                            points = points + 3;
                       elseif Chol >= 200 && Chol < 240
                            points = points + 6;
                       elseif Chol >= 240 && Chol < 280
                            points = points + 8;
                       else
                            points = points + 10;
                       end
                       % Points based on smoking status
                       if activeSmoke == "Yes"
                            points = points + 7;
                       end
                       
                    elseif age >= 50 && age < 60
                       % Points based on age
                       points = points + 7;
                       % Points based on cholestrol levels
                       if Chol < 160
                            points = points + 0;
                       elseif Chol >= 160 && Chol < 200
                            points = points + 2;
                       elseif Chol >= 200 && Chol < 240
                            points = points + 4;
                       elseif Chol >= 240 && Chol < 280
                            points = points + 5;
                       else
                            points = points + 7;
                       end
                       % Points based on smoking status
                       if activeSmoke == "Yes"
                            points = points + 4;
                       end
                       
                    elseif age >= 60 && age < 70
                       % Points based on age
                       points = points + 11;
                       % Points based on cholestrol levels
                       if Chol < 160
                            points = points + 0;
                       elseif Chol >= 160 && Chol < 200
                            points = points + 1;
                       elseif Chol >= 200 && Chol < 240
                            points = points + 2;
                       elseif Chol >= 240 && Chol < 280
                            points = points + 3;
                       else
                            points = points + 4;
                       end
                       % Points based on smoking status
                       if activeSmoke == "Yes"
                            points = points + 2;
                       end
                       
                    else
                       % Points based on age
                       points = points + 15;
                       % Points based on cholestrol levels
                       if Chol < 160
                            points = points + 0;
                       elseif Chol >= 160 && Chol < 200
                            points = points + 1;
                       elseif Chol >= 200 && Chol < 240
                            points = points + 1;
                       elseif Chol >= 240 && Chol < 280
                            points = points + 2;
                       else
                            points = points + 2;
                       end
                       % Points based on smoking status
                       if activeSmoke == "Yes"
                            points = points + 1;
                       end
                       
                    end

                    % Points based on HDL
                       if HDL >= 60
                            points = points - 1;
                       elseif HDL < 60 && HDL >=50
                            points = points + 0;
                       elseif HDL < 50 && HDL >= 40
                            points = points + 1;
                       else
                            points = points + 2;
                       end
                    % Points based on SBP
                       if SBP <120
                            points = points + 0;
                       elseif SBP >= 120 && SBP < 130
                            points = points + 1;
                       elseif SBP >= 130 && SBP < 140
                            points = points + 2;
                       elseif SBP >= 140 && SBP < 150
                            points = points + 3;
                       else
                            points = points + 4;
                       end

                       % Calculating 10-year Risk 
                       if points < 9
                            app.YearRiskEditField.Value = 0;
                       elseif points >= 9 && points < 13
                            app.YearRiskEditField.Value = 1;
                       elseif points >= 13 && points < 15
                            app.YearRiskEditField.Value = 2;
                       elseif points == 15
                            app.YearRiskEditField.Value = 3;
                       elseif points == 16
                            app.YearRiskEditField.Value = 4;
                       elseif points == 17
                            app.YearRiskEditField.Value = 5;
                       elseif points == 18
                            app.YearRiskEditField.Value = 6;
                       elseif points == 19
                            app.YearRiskEditField.Value = 8;
                       elseif points == 20
                            app.YearRiskEditField.Value = 11;
                       elseif points == 21
                            app.YearRiskEditField.Value = 14;
                       elseif points == 22
                            app.YearRiskEditField.Value = 17;
                       elseif points == 23
                            app.YearRiskEditField.Value = 22;
                       elseif points == 24
                            app.YearRiskEditField.Value = 27;
                       else
                            app.YearRiskEditField.Value = 30;
                       end

                       % Calculating Estimated Heart rate
                       if points < 1
                            app.EstHeartAgeEditField.Value = 30;
                       elseif points == 1
                            app.EstHeartAgeEditField.Value = 31;
                       elseif points == 2
                            app.EstHeartAgeEditField.Value = 34;
                       elseif points == 3
                            app.EstHeartAgeEditField.Value = 36;
                       elseif points == 4
                            app.EstHeartAgeEditField.Value = 39;
                       elseif points == 5
                            app.EstHeartAgeEditField.Value = 42;
                       elseif points == 6
                            app.EstHeartAgeEditField.Value = 45;
                       elseif points == 7
                            app.EstHeartAgeEditField.Value = 48;
                       elseif points == 8
                            app.EstHeartAgeEditField.Value = 51;
                       elseif points == 9
                            app.EstHeartAgeEditField.Value = 55;
                       elseif points == 10
                            app.EstHeartAgeEditField.Value = 59;
                       elseif points == 11
                            app.EstHeartAgeEditField.Value = 64;
                       elseif points == 12
                            app.EstHeartAgeEditField.Value = 68;
                       elseif points == 13
                            app.EstHeartAgeEditField.Value = 73;
                       elseif points == 14
                            app.EstHeartAgeEditField.Value = 79;
                       else
                            app.EstHeartAgeEditField.Value = 80;
                       end
                        

                      % Determining Risk Category
                    YearRisk = app.YearRiskEditField.Value;
                        if YearRisk < 10
                            app.RiskEditField.Value="Low Risk";
                        elseif YearRisk >=10 && YearRisk <= 19
                            app.RiskEditField.Value="Moderate Risk";
                        elseif YearRisk >=20 && YearRisk <=30
                            app.RiskEditField.Value= "High Risk";
                        else
                            app.RiskEditField.Value='Invalid Input' ;
                        end


                end
            else
                app.zeroCheck.Text = "Invalid: Input consists zeros!";
            end                       
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.AgeEditField.Value = 0;
            app.BiologicalSexDropDown.Value = "Male";
            app.WeightEditField.Value = 0;
            app.HeightEditField.Value = 0;
            app.WaistCircEditField.Value = 0;
            app.HipCircEditField.Value = 0;
            app.ActiveSmokerDropDown.Value = "No";
            app.SBPEditField.Value = 0;
            app.CholestrolLevelEditField.Value = 0;
            app.HDLEditField.Value = 0;
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [fileName,filePath] = uigetfile('*.csv','Select CSV File');
            if fileName ~=0
                data = readtable(fullfile(filePath, fileName));
                minHR = table2array(data(2:end,2));
                maxHR = table2array(data(2:end,3));
                HRV = table2array(data(2:end,7));
                days = 1:length(maxHR);

                age = app.AgeEditField.Value;
                if age ~= 0
                    theoreticalHR = 208 - age*0.7;
                else
                    theoreticalHR = 0;
                end
                theoreticalMaxHR = zeros(1,length(days))+theoreticalHR;
                grid(app.HeartRateAnalysisAxes, 'on');
                plot(app.HeartRateAnalysisAxes, days, minHR, days, maxHR, days, theoreticalMaxHR);
                xlabel(app.HeartRateAnalysisAxes,"Days");
                ylabel(app.HeartRateAnalysisAxes,"Heart Rate (BPM)");
                title(app.HeartRateAnalysisAxes,"Heart Rate Analysis");
                legend(app.HeartRateAnalysisAxes,"Min. Heart Rate","Max Heart Rate","Theoretical Max Heart Rate");
                
                grid(app.HRVAxes, 'on');
                plot(app.HRVAxes, days, HRV);
                xlabel(app.HRVAxes,"Days");
                ylabel(app.HRVAxes,"ms");
                title(app.HRVAxes,"Average Heart Rate Variability");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.AgeEditFieldLabel = uilabel(app.InputParametersPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.AgeEditField.Limits = [0 Inf];
            app.WeightEditFieldLabel = uilabel(app.InputParametersPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.WeightEditField.Limits = [0 Inf];
            app.WaistCircEditFieldLabel = uilabel(app.InputParametersPanel);
            app.WaistCircEditFieldLabel.Text = 'Waist Circ.';
            app.WaistCircEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.WaistCircEditField.Limits = [0 Inf];
            app.HipCircEditFieldLabel = uilabel(app.InputParametersPanel);
            app.HipCircEditFieldLabel.Text = 'Hip Circ.';
            app.HipCircEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.HipCircEditField.Limits = [0 Inf];
            app.SBPEditFieldLabel = uilabel(app.InputParametersPanel);
            app.SBPEditFieldLabel.Text = 'SBP';
            app.SBPEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.SBPEditField.Limits = [0 Inf];
            app.yearsLabel = uilabel(app.InputParametersPanel);
            app.yearsLabel.Text = 'years';
            app.kgLabel = uilabel(app.InputParametersPanel);
            app.kgLabel.Text = 'kg';
            app.inLabel = uilabel(app.InputParametersPanel);
            app.inLabel.Text = 'in';
            app.mmHgLabel = uilabel(app.InputParametersPanel);
            app.mmHgLabel.Text = 'mmHg';
            app.ActiveSmokerDropDownLabel = uilabel(app.InputParametersPanel);
            app.ActiveSmokerDropDownLabel.Text = 'Active Smoker';
            app.ActiveSmokerDropDown = uidropdown(app.InputParametersPanel);
            app.ActiveSmokerDropDown.Items = {'No', 'Yes'};
            app.ActiveSmokerDropDown.Value = 'No';
            app.mLabel = uilabel(app.InputParametersPanel);
            app.mLabel.Text = 'm';
            app.HeightEditFieldLabel = uilabel(app.InputParametersPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.CholestrolLevelEditFieldLabel = uilabel(app.InputParametersPanel);
            app.CholestrolLevelEditFieldLabel.Text = 'Cholestrol Level';
            app.CholestrolLevelEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.CholestrolLevelEditField.Limits = [0 Inf];
            app.mgdLLabel = uilabel(app.InputParametersPanel);
            app.mgdLLabel.Text = 'mg/dL';
            app.BiologicalSexDropDown = uidropdown(app.InputParametersPanel);
            app.BiologicalSexDropDown.Items = {'Male', 'Female'};
            app.BiologicalSexDropDown.Value = 'Male';
            app.BiologicalSexDropDownLabel = uilabel(app.InputParametersPanel);
            app.BiologicalSexDropDownLabel.Text = 'Biological Sex';
            app.HDLEditFieldLabel = uilabel(app.InputParametersPanel);
            app.HDLEditFieldLabel.Text = 'HDL';
            app.HDLEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.HDLEditField.Limits = [0 Inf];
            app.mgdLLabel_2 = uilabel(app.InputParametersPanel);
            app.mgdLLabel_2.Text = 'mg/dL';
            app.CalculateButton = uibutton(app.InputParametersPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Icon = 'calculator.png';
            app.CalculateButton.Text = 'Calculate';
            app.ClearButton = uibutton(app.InputParametersPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = 'eraser.png';
            app.ClearButton.Text = 'Clear';
            app.Label = uilabel(app.InputParametersPanel);
            app.zeroCheck = uilabel(app.InputParametersPanel);
            app.zeroCheck.Text = '';
            app.inLabel_2 = uilabel(app.InputParametersPanel);
            app.inLabel_2.Text = 'in';
            app.StatisticalOutputsPanel = uipanel(app.UIFigure);
            app.StatisticalOutputsPanel.Title = 'Statistical Outputs';
            app.MaxHREditFieldLabel = uilabel(app.StatisticalOutputsPanel);
            app.MaxHREditFieldLabel.Text = 'Max HR';
            app.MaxHREditField = uieditfield(app.StatisticalOutputsPanel, 'numeric');
            app.RREditFieldLabel = uilabel(app.StatisticalOutputsPanel);
            app.RREditFieldLabel.Text = 'RR ';
            app.RREditField = uieditfield(app.StatisticalOutputsPanel, 'numeric');
            app.BMIEditFieldLabel = uilabel(app.StatisticalOutputsPanel);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.StatisticalOutputsPanel, 'numeric');
            app.WHREditFieldLabel = uilabel(app.StatisticalOutputsPanel);
            app.WHREditFieldLabel.Text = 'WHR';
            app.WHREditField = uieditfield(app.StatisticalOutputsPanel, 'numeric');
            app.YearRiskEditFieldLabel = uilabel(app.StatisticalOutputsPanel);
            app.YearRiskEditFieldLabel.Text = '10 Year Risk';
            app.YearRiskEditField = uieditfield(app.StatisticalOutputsPanel, 'numeric');
            app.Label_2 = uilabel(app.StatisticalOutputsPanel);
            app.Label_2.Text = '%';
            app.bpmLabel = uilabel(app.StatisticalOutputsPanel);
            app.bpmLabel.Text = 'bpm';
            app.msLabel = uilabel(app.StatisticalOutputsPanel);
            app.msLabel.Text = 'ms';
            app.EstHeartAgeEditFieldLabel = uilabel(app.StatisticalOutputsPanel);
            app.EstHeartAgeEditFieldLabel.Text = 'Est. Heart Age';
            app.EstHeartAgeEditField = uieditfield(app.StatisticalOutputsPanel, 'numeric');
            app.yearsLabel_2 = uilabel(app.StatisticalOutputsPanel);
            app.yearsLabel_2.Text = 'years';
            app.RiskEditFieldLabel = uilabel(app.StatisticalOutputsPanel);
            app.RiskEditFieldLabel.Text = 'Risk';
            app.RiskEditField = uieditfield(app.StatisticalOutputsPanel, 'text');
            app.GraphicalOutputsPanel = uipanel(app.UIFigure);
            app.GraphicalOutputsPanel.Title = 'Graphical Outputs';
            app.HeartRateAnalysisAxes = uiaxes(app.GraphicalOutputsPanel);
            title(app.HeartRateAnalysisAxes, 'Max HR vs. Min. HR')
            xlabel(app.HeartRateAnalysisAxes, 'Days')
            ylabel(app.HeartRateAnalysisAxes, 'Heart Rate (BPM)')
            zlabel(app.HeartRateAnalysisAxes, 'Z')
            app.HRVAxes = uiaxes(app.GraphicalOutputsPanel);
            title(app.HRVAxes, 'Average Heart Rate Variability')
            xlabel(app.HRVAxes, 'Days')
            ylabel(app.HRVAxes, 'Heart Rate (BPM)')
            zlabel(app.HRVAxes, 'Z')
            app.UploadButton = uibutton(app.GraphicalOutputsPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Icon = 'upload.png';
            app.UploadButton.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end